classdef CCEDashboardFrontEnd < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFig                         matlab.ui.Figure
        VersionLabel                    matlab.ui.control.Label
        EndTimeEditField                matlab.ui.control.EditField
        EndTimeLabel                    matlab.ui.control.Label
        StarttimeEditField              matlab.ui.control.EditField
        StarttimeLabel                  matlab.ui.control.Label
        ConnectionMessage               matlab.ui.control.Label
        AFDatabaseEditField             matlab.ui.control.EditField
        AFDatabaseEditFieldLabel        matlab.ui.control.Label
        AFServerEditField               matlab.ui.control.EditField
        AFServerEditFieldLabel          matlab.ui.control.Label
        ConnectRefreshButton            matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        CoordinatorsTab                 matlab.ui.container.Tab
        CoordPlotLabel                  matlab.ui.control.Label
        PlotSelectedCoordsButton        matlab.ui.control.Button
        CoordTabGroup                   matlab.ui.container.TabGroup
        CoordinatorStateHistoryTab      matlab.ui.container.Tab
        CoordStateHistoryAx             matlab.ui.control.UIAxes
        CoordinatorStateDistributionTab  matlab.ui.container.Tab
        CoordStateDistAx                matlab.ui.control.UIAxes
        CoordTable                      matlab.ui.control.Table
        CalculationsTab                 matlab.ui.container.Tab
        CalcPlotPanel                   matlab.ui.container.Panel
        CalcPlotTabs                    matlab.ui.container.TabGroup
        StateDistributionTab            matlab.ui.container.Tab
        CalcStateDistAxes               matlab.ui.control.UIAxes
        StateHistoryTab                 matlab.ui.container.Tab
        CalcStateHistAxes               matlab.ui.control.UIAxes
        OutputvsExecutionTimeTab        matlab.ui.container.Tab
        OutputvsExecAxes                matlab.ui.control.UIAxes
        LastErrorHistoryTab             matlab.ui.container.Tab
        LastErrorHistAxes               matlab.ui.control.UIAxes
        CalculationLagTab               matlab.ui.container.Tab
        CalcLagAxes                     matlab.ui.control.UIAxes
        CalcListPanel                   matlab.ui.container.Panel
        CalcPlotStatus                  matlab.ui.control.Label
        PlotselectedButton              matlab.ui.control.Button
        ShowFilterPanelButton           matlab.ui.control.Image
        RequestdisableonselectedButton  matlab.ui.control.Button
        ReenableselectedButton          matlab.ui.control.Button
        FilteredCalcTab                 matlab.ui.control.Table
        RefreshButton                   matlab.ui.control.Button
        FilteredCalculationsLabel       matlab.ui.control.Label
        FilterPanel                     matlab.ui.container.Panel
        HideFilterPanelButton           matlab.ui.control.Image
        CalculationCoordinatorListBox   matlab.ui.control.ListBox
        CalculationCoordinatorListBoxLabel  matlab.ui.control.Label
        RefreshCoordinatorBox           matlab.ui.control.Button
        CurrentCalculationStateListBox  matlab.ui.control.ListBox
        CurrentCalculationStateListBoxLabel  matlab.ui.control.Label
        CalculationStateCoordinatorFilteringLabel  matlab.ui.control.Label
        SelectAllButtonTemplate         matlab.ui.control.Button
        TemplateFilterEditField         matlab.ui.control.EditField
        FilterEditFieldLabel            matlab.ui.control.Label
        TemplateFilteringLabel          matlab.ui.control.Label
        TemplateFilteringTree           matlab.ui.container.Tree
        SelectAllButtonElement          matlab.ui.control.Button
        ElementFilteringLabel           matlab.ui.control.Label
        ElementFilteringTree            matlab.ui.container.Tree
    end


    properties (Access = public)
        BackEnd CCEDashboardBackEnd
    end

    properties (Access = private)
        TemplateFilterString string % Description
        AllCalcsFlag logical = false;
        AllTemplatesFlag logical = false;
    end

    methods (Access = public)

        function populateElementTree(app, elementNames, elementChildNames)
            %Adds root level and first level children
            app.ElementFilteringTree.Children.delete;
            app.ElementFilteringTree.SelectedNodes = [];
            for iElement = 1:numel(elementNames)
                %Root level
                rootNode = uitreenode(app.ElementFilteringTree, "Text",  elementNames(iElement));

                %First level children
                for iChild = 1:numel(elementChildNames{iElement})
                    uitreenode(rootNode, "Text", elementChildNames{iElement}(iChild));
                end
            end

        end

        function populateConstantComponents(app)
            %Calculation state filter window
            [~, text] = enumeration('cce.CalculationState');
            text{end + 1} = 'PIPointMissing';
            app.CurrentCalculationStateListBox.Items = text;

        end

        function populateTemplateTree(app, templateNames)
            app.TemplateFilteringTree.Children.delete;
            app.TemplateFilteringTree.SelectedNodes = [];
            for iElement = 1:numel(templateNames)
                uitreenode(app.TemplateFilteringTree, "Text",  templateNames(iElement));
            end

        end

        function collectCalcsOfRootAndTemplate(app)
            %Collates front end element and template filtering, populate
            %back end property

            %Collect filtering for elements
            %If none are selected, its as good as all
            if isempty(app.ElementFilteringTree.SelectedNodes)
                app.AllCalcsFlag = true;
            end
            if app.AllCalcsFlag
                elemParentTextArray = "";
            else
                elemParentTextArray = app.getNodePath(app.ElementFilteringTree.SelectedNodes);
            end

            %Collect filtering for templates
            if isempty(app.TemplateFilteringTree.SelectedNodes)
                app.AllTemplatesFlag = true;
            end
            if app.AllTemplatesFlag
                calcTemplateArray = "CCECalculation";
            else
                calcTemplateArray = string({app.TemplateFilteringTree.SelectedNodes.Text});
            end

            %Get calc back end items
            app.BackEnd.collectCalcsOfRootAndTemplate(elemParentTextArray, calcTemplateArray);

        end

        function createCalcPlots(app)
            %Calc state distrib
            app.MainFig.Pointer = 'watch';

            %Get start and end times
            st = app.StarttimeEditField.Value;
            et = app.EndTimeEditField.Value;

            app.plotCalcState(st, et);
            app.plotExeTimes(st, et);
            app.plotErrorHist(st, et);

            app.CalcPlotStatus.Text = "Finished.";
            app.MainFig.Pointer = 'arrow';
        end

        function plotCalcState(app, st, et)
            app.CalcPlotStatus.Text = "Pulling CalcState...";
            drawnow;

            %Get history from back end
            [calcStateCellArray, keepIdx] = app.BackEnd.getCalcStateHist(st, et);

            cla(app.CalcStateHistAxes, 'reset')
            hold(app.CalcStateHistAxes, 'on')
            cla(app.CalcStateDistAxes, 'reset')
            hold(app.CalcStateDistAxes, 'on')


            if ~isempty(calcStateCellArray)
                app.CalcPlotStatus.Text = "Plotting CalcState...";
                drawnow;

                colororder(app.CalcStateHistAxes, app.generateCmap(numel(calcStateCellArray)));
                colororder(app.CalcStateDistAxes, app.generateCmap(numel(calcStateCellArray)));

                %History plot
                %Populate graph
                statePlotNumArray = [];
                timeArray = [];

                [enum, enumNames] = enumeration('cce.CalculationState');
                enumRefTable = app.BackEnd.makePlottableEnum(enum, enumNames);

                %Loop through coordinated creating plotting arrays
                for iCalc = 1:numel(calcStateCellArray)
                    calcTab = calcStateCellArray{iCalc};
                    cs = cce.CalculationState(calcTab.calcState);
                    [~, stateLoc] = ismember(cs, enumRefTable.OrigVal);
                    statePlotNums = enumRefTable.PlotVal(stateLoc);

                    %Get nums and times for limits
                    timeArray = [timeArray; calcTab.Time]; %#ok<AGROW>

                    %Change enum to plottable numbers
                    statePlotNumArray = [statePlotNumArray; statePlotNums]; %#ok<AGROW>

                    %Plot
                    stairs(app.CalcStateHistAxes, calcTab.Time, statePlotNums, Marker ='.');
                end

                %Formatting
                yticks(app.CalcStateHistAxes, unique(statePlotNumArray));
                [~, labelsIdx] = ismember(unique(statePlotNumArray), enumRefTable.PlotVal);
                yticklabels(app.CalcStateHistAxes, enumRefTable.Name(labelsIdx));
                xlim(app.CalcStateHistAxes, [min(timeArray) - minutes(1), max(timeArray) + minutes(1)])
                ylim(app.CalcStateHistAxes, [min(double(statePlotNumArray)) - 0.1, max(double(statePlotNumArray)) + 0.1]);

                legendArray = app.BackEnd.SelectedCalcInfoTab.Name(keepIdx);
                legend(app.CalcStateHistAxes, legendArray);
                title(app.CalcStateHistAxes, "Calculation State History")

                %DISTRIBUTION PLOT 
                X = categorical(enumNames);
                X = reordercats(X, enumNames);
                Y = zeros(numel(calcStateCellArray), numel(X));

                for iCalc = 1:numel(calcStateCellArray)

                    %Get data
                    calcTab = calcStateCellArray{iCalc};
                    calcTab(isnan(calcTab.calcState) | isnat(calcTab.Time), :) = [];
                    if ~isempty(calcTab) %Must both be non empty
                        state = calcStateCellArray{iCalc}.calcState;
                        durationArray = [hours(diff(calcStateCellArray{iCalc}.Time)); 0];
                        totTime = sum(durationArray);
                        for iState = 1:numel(X)
                            % stateDurationArray(iState) =
                            Y(iCalc, iState) = sum(durationArray(state == iState - 1))/totTime;
                        end

                    end
                end

                bar(app.CalcStateDistAxes, X, Y);
                legend(app.CalcStateDistAxes, legendArray);
                title(app.CalcStateDistAxes, "Calculation State Distribution")
            else
                title(app.CalcStateDistAxes, "No PIPoint data for selected range.");
                title(app.CalcStateHistAxes, "No PIPoint data for selected range.");
            end
        end

        function plotExeTimes(app, st, et)

            [calcTimesCellArray, keepIdx] = app.BackEnd.getCalcTimesHist(st, et);
            app.CalcPlotStatus.Text = "Pulling CalcTimes...";
            drawnow;

            cla(app.OutputvsExecAxes, 'reset')
            hold(app.OutputvsExecAxes, 'on')
            cla(app.CalcLagAxes, 'reset')
            hold(app.CalcLagAxes, 'on')

            if isempty(calcTimesCellArray)
                title(app.CalcLagAxes, "No PIPoint data for selected range.");
                title(app.OutputvsExecAxes, "No PIPoint data for selected range.");
            else
                %Plot exe time vs output time
                app.CalcPlotStatus.Text = "Plotting CalcTimes...";
                drawnow;
                colororder(app.OutputvsExecAxes, app.generateCmap(numel(calcTimesCellArray)));

                for iCalc = 1:numel(calcTimesCellArray)
                    stairs(app.OutputvsExecAxes, calcTimesCellArray{iCalc}.lastCalcTime,...
                        calcTimesCellArray{iCalc}.Time, "Marker", ".");
                end

                %Plot diag best line.
                xLims = app.OutputvsExecAxes.XLim;
                plot(app.OutputvsExecAxes, xLims, xLims, '--k', "LineWidth", 0.25);

                legendArray = app.BackEnd.SelectedCalcInfoTab.Name(keepIdx);
                legend(app.OutputvsExecAxes, [legendArray; "Optimal Line"]);
                xlabel(app.OutputvsExecAxes, "Last Calc Time");
                ylabel(app.OutputvsExecAxes, "Output Time");
                title(app.OutputvsExecAxes, "Output time vs Last Calc Time")

                %Plot calc lag plot
                coordNums = app.BackEnd.SelectedCalcInfoTab.("Coord #")(keepIdx);
                uniqueCoordNums = unique(coordNums);
                cmap = app.generateCmap(numel(uniqueCoordNums));
                p = struct();

                for iCalc = 1:numel(calcTimesCellArray)
                    calculationLag = mean(calcTimesCellArray{iCalc}.Time - calcTimesCellArray{iCalc}.lastCalcTime);
                    p.("Coord" + string(coordNums(iCalc))) = plot(app.CalcLagAxes, [-calculationLag, minutes(0)], [iCalc, iCalc],...
                        "Color", cmap(uniqueCoordNums == coordNums(iCalc), :));
                end

                %Create a legend
                fields = fieldnames(p);
                for iField = 1:numel(fields)
                    lgdHandles(iField) = p.(fields{iField});
                end

                xline(app.CalcLagAxes, minutes(0), '--k', 'DisplayName', "Current", "LineWidth", 0.25)
                legend(app.CalcLagAxes, lgdHandles, "Coord " + string(uniqueCoordNums));
                title(app.CalcLagAxes, "Mean Calculation Lag")

                %Create ticks
                yticks(app.CalcLagAxes, 1:numel(calcTimesCellArray));
                yticklabels(app.CalcLagAxes, legendArray);
                ylim(app.CalcLagAxes, [0, numel(calcTimesCellArray) + 1]);

            end
        

        end

        function plotErrorHist(app, st, et)

            [calcErrorCellArray, keepIdx] = app.BackEnd.getCalcErrorHist(st, et);
            app.CalcPlotStatus.Text = "Pulling CalcErrors.";
            drawnow;
            cla(app.LastErrorHistAxes, 'reset')
            hold(app.LastErrorHistAxes, 'on')
            if isempty(calcErrorCellArray)
                title(app.LastErrorHistAxes, "No PIPoint data for selected range.");
            else
                app.CalcPlotStatus.Text = "Plotting CalcErrors.";
                drawnow;
                colororder(app.LastErrorHistAxes, app.generateCmap(numel(calcErrorCellArray)));
                [enum, enumNames] = enumeration('cce.CalculationErrorState');
                enumRefTable = app.BackEnd.makePlottableEnum(enum, enumNames);
                timeArray = [];
                statePlotNumArray = [];

                for iCalc = 1:numel(calcErrorCellArray)
                    calcTab = calcErrorCellArray{iCalc};
                    cs = cce.CalculationErrorState(calcTab.lastError);
                    [~, stateLoc] = ismember(cs, enumRefTable.OrigVal);
                    statePlotNums = enumRefTable.PlotVal(stateLoc);

                    %Get nums and times for limits
                    timeArray = [timeArray; calcTab.Time]; %#ok<AGROW>

                    %Change enum to plottable numbers
                    statePlotNumArray = [statePlotNumArray; statePlotNums]; %#ok<AGROW>

                    stairs(app.LastErrorHistAxes, calcTab.Time,...
                        statePlotNums, "Marker", ".");

                end
            

                yticks(app.LastErrorHistAxes, unique(statePlotNumArray));
                [~, labelsIdx] = ismember(unique(statePlotNumArray), enumRefTable.PlotVal);
                yticklabels(app.LastErrorHistAxes, enumRefTable.Name(labelsIdx));
                xlim(app.LastErrorHistAxes, [min(timeArray) - minutes(1), max(timeArray) + minutes(1)])
                ylim(app.LastErrorHistAxes, [min(double(statePlotNumArray)) - 0.1, max(double(statePlotNumArray)) + 0.1]);

                legendArray = app.BackEnd.SelectedCalcInfoTab.Name(keepIdx);
                legend(app.LastErrorHistAxes, legendArray);
                title(app.LastErrorHistAxes, "Last Error History");
            end

        end

        function showNeedCalcRefresh(app, needsRefresh)
            if needsRefresh
                app.RefreshCoordinatorBox.BackgroundColor = [1 0.8 0.8];
                app.RefreshButton.BackgroundColor = [1 0.8 0.8];
            else
                app.RefreshCoordinatorBox.BackgroundColor = [0.96 0.96 0.96];
                app.RefreshButton.BackgroundColor = [0.96 0.96 0.96];
            end
        end
    end

    methods (Static)

        function parentTextArray = getNodePath(selectedNodes)
            %getNodePath Recursively loops through parents to get the full
            %path for selected element.

            %Get parents above
            parentTextArray = cell(numel(selectedNodes), 1);
            for iParent = 1:numel(selectedNodes)
                parentTextArray{iParent} = string(selectedNodes(iParent).Text);
            end

            for iParent = 1:numel(parentTextArray)
                parentTextArray{iParent} = getParentList(selectedNodes(iParent), parentTextArray{iParent});
            end

            function parentTextArray = getParentList(node, parentTextArray)
                nodeParent = node.Parent;
                if strcmp(class(nodeParent), class(node))
                    parentTextArray = [string(nodeParent.Text), parentTextArray];
                    parentTextArray = getParentList(nodeParent, parentTextArray);
                end
            end
        end

        function addChildrenToTreeNode(nodeSrc, childrenTextArray)
            %Adds children to source node with given childrenTextArray
            %names. If name already exists at level, won't be added again.
            if isempty(nodeSrc.Children)
                for iElement = 1:numel(childrenTextArray)
                    uitreenode(nodeSrc, "Text",  childrenTextArray(iElement));
                end
            end

        end

        function cmap = generateCmap(numCols)
            if numCols <= 9
                colScheme = "Dark2";
                cmap = brewermap(numCols , colScheme);
            else
                cmap = turbo(numCols);
            end
        end
    end

    methods (Access = private)

        function readDefaultServerDBName(app)
            % Open the file
            fileID = fopen('dashboardDefaults.txt', 'r');

            % Check if file opened successfully
            if fileID == -1
                server = 'ons-opcdev.optinum.local';
                db = 'CCEProd';
            else
                % Read the server name
                server = fgetl(fileID);

                % Read the database name
                db = fgetl(fileID);

                % Close the file
                fclose(fileID);
            end

            %Set names
            app.AFServerEditField.Value = server;
            app.AFDatabaseEditField.Value = db;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Instantiate back end
            app.BackEnd = CCEDashboardBackEnd(app);

            %Change default server and db
            app.readDefaultServerDBName;

            %Populate constant components
            app.populateConstantComponents;

            %Show version number
            version = "1.5";
            app.VersionLabel.Text = "V" + version;
        end

        % Button pushed function: ConnectRefreshButton
        function connectButtonPushed(app, event)
            if isempty(app.AFServerEditField.Value) || isempty(app.AFDatabaseEditField.Value)
                app.ConnectionMessage.Text = "Empty Server/DB Field";
                drawnow;
                app.MainFig.Pointer = 'arrow';
                return;
            end

            app.MainFig.Pointer = 'watch';
            app.ConnectionMessage.Text = "Connecting...";
            drawnow;

            try
                [success, message] = app.BackEnd.connectToDB(app.AFServerEditField.Value,...
                    app.AFDatabaseEditField.Value);
            catch err
                success = false;
                message = err.message;
            end

            if success
                %Reset items
                app.CalculationCoordinatorListBox.Items = {};
                app.CalculationCoordinatorListBox.Value = {};

                %COORDINATOR SIDE
                %Get available coordinators
                coordinators = app.BackEnd.getCoordinators;
                app.CoordTable.Data = coordinators{:, :};
                app.CoordTable.Selection = [];

                %CALCULATION SIDE
                %Update root elem names
                [rootElementNames, rootElementChildrenNames] = app.BackEnd.getRootElementsWithChildren;
                app.populateElementTree(rootElementNames, rootElementChildrenNames);

                %Update template names
                templateNames = app.BackEnd.getCalcTemplates("");
                app.populateTemplateTree(templateNames);

                %FEEDBACK
                app.ConnectionMessage.Text = "Connected.";
            else
                app.ConnectionMessage.Text = message;
                drawnow;
            end
            app.showNeedCalcRefresh(true);
            app.MainFig.Pointer = 'arrow';

        end

        % Selection changed function: TemplateFilteringTree
        function templateFilteringTreeSelectionChanged(app, event)
            app.AllTemplatesFlag = false;
            app.showNeedCalcRefresh(true);
        end

        % Node expanded function: ElementFilteringTree
        function elementFilteringTreeNodeExpanded(app, event)
            app.AllCalcsFlag = false;

            selectedNode = event.Node;

            %Loop through children if there are, check if any have
            %children, assign if needed
            parentTextArray = app.getNodePath(selectedNode);
            childNodes = selectedNode.Children;
            for iChild = 1:numel(childNodes)

                childText = string(childNodes(iChild).Text);
                subParentTextArray = [string(parentTextArray{:, :}), childText];
                childElementNames = app.BackEnd.getChildElements(subParentTextArray);
                if ~isempty(childElementNames)
                    app.addChildrenToTreeNode(childNodes(iChild), childElementNames);
                end
            end

        end

        % Value changed function: TemplateFilterEditField
        function templateFilterEditFieldValueChanged(app, event)
            app.AllTemplatesFlag = false;
            value = app.TemplateFilterEditField.Value;
            app.TemplateFilterString = value;

            %Update template names
            templateNames = app.BackEnd.getCalcTemplates(app.TemplateFilterString);
            app.populateTemplateTree(templateNames);
            app.showNeedCalcRefresh(true);
        end

        % Button pushed function: SelectAllButtonElement
        function selectAllButtonElementPushed(app, event)
            app.AllCalcsFlag = true;
            collapse(app.ElementFilteringTree)

            for iChild = 1:numel(app.ElementFilteringTree.Children)
                app.ElementFilteringTree.SelectedNodes = ...
                    [app.ElementFilteringTree.SelectedNodes; app.ElementFilteringTree.Children(iChild)];
            end
            app.showNeedCalcRefresh(true);
        end

        % Button pushed function: SelectAllButtonTemplate
        function selectAllButtonTemplatePushed(app, event)
            app.AllTemplatesFlag = true;
            app.TemplateFilteringTree.Multiselect = 'on';
            for iChild = 1:numel(app.TemplateFilteringTree.Children)
                app.TemplateFilteringTree.SelectedNodes = ...
                    [app.TemplateFilteringTree.SelectedNodes; app.TemplateFilteringTree.Children(iChild)];
            end
            app.showNeedCalcRefresh(true);
        end

        % Button pushed function: RefreshButton
        function refreshButtonPushed(app, event)
            app.MainFig.Pointer = 'watch';
            drawnow;

            %Refresh db cache
            app.BackEnd.DataConnector.refreshAFDbCache;

            %First level filtering
            app.collectCalcsOfRootAndTemplate;

            %Second level filtering
            statesList = app.CurrentCalculationStateListBox.Value;
            app.BackEnd.filterByCalcState(statesList);

            %Get used coords
            coordFilterList = str2double(app.CalculationCoordinatorListBox.Value);

            %Third level filtering
            app.BackEnd.filterCalcsByCoord(coordFilterList);

            %Get calc back end items
            %Apply calc
            calcTab = app.BackEnd.getCalcInfo;
            app.FilteredCalcTab.Data = calcTab;
            app.FilteredCalcTab.Selection = [];

            app.RefreshButton.BackgroundColor = [0.96, 0.96, 0.96];
            app.MainFig.Pointer = 'arrow';
            drawnow;
        end

        % Button pushed function: PlotSelectedCoordsButton
        function plotSelectedCoordsButtonPushed(app, event)
            %Update coordinator state plot
            app.CoordPlotLabel.Text = "Pulling history...";
            app.MainFig.Pointer = 'watch';
            drawnow;

            %DATA PULLING
            %Get start and end times
            st = app.StarttimeEditField.Value;
            et = app.EndTimeEditField.Value;

            %Get history from back end
            rowIdx = app.CoordTable.Selection;
            tabLen = numel(app.CoordTable.Data(:, 1));
            coordStateCellArray = app.BackEnd.getCoordinatorStateHistory(rowIdx, tabLen, st, et);

            %PREP FOR PLOTTING
            app.CoordPlotLabel.Text = "Plotting...";
            drawnow;
            removeCoord = false(numel(coordStateCellArray), 1);

            %Remove nans
            for iCoord = 1:numel(coordStateCellArray)
                coordTab = coordStateCellArray{iCoord};

                %Remove nans
                coordTab(isnan(coordTab.coordState) | isnat(coordTab.Time), :) = [];
                coordStateCellArray{iCoord} = coordTab;

                %Remove coordinator if its empty
                if isempty(coordTab)
                    removeCoord(iCoord) = true;
                end
            end
            coordStateCellArray(removeCoord) = [];

            cla(app.CoordStateHistoryAx, 'reset')
            hold(app.CoordStateHistoryAx, 'on')
            cla(app.CoordStateDistAx, 'reset')
            hold(app.CoordStateDistAx, 'on')

            if isempty(coordStateCellArray)
                app.CoordPlotLabel.Text = "No PI tag data for selected period.";
                title(app.CoordStateDistAx, "No PIPoint data for selected range");
                title(app.CoordStateHistoryAx, "No PIPoint data for selected range");
                app.MainFig.Pointer = 'arrow';
                drawnow;
                return
            end

            %HISTORY PLOT
            %Populate graph
            stateNumArray = [];
            timeArray = [];

            %Loop through coordinated creating plotting arrays
            for iCoord = 1:numel(coordStateCellArray)
                coordTab = coordStateCellArray{iCoord};
                cs = cce.CoordinatorState(coordTab.coordState);

                %Get nums and times for limits
                stateNumArray = [stateNumArray; cs.real]; %#ok<AGROW>
                timeArray = [timeArray; coordTab.Time]; %#ok<AGROW>

                %Get plotting values
                [x, y] = enumeration(cs);

                %Plot
                stairs(app.CoordStateHistoryAx, coordTab.Time, cs, Marker ='.');
            end

            %Formatting
            yticks(app.CoordStateHistoryAx, int32(x));
            yticklabels(app.CoordStateHistoryAx, y);
            xlim(app.CoordStateHistoryAx, [min(timeArray) - minutes(1), max(timeArray) + minutes(1)])
            ylim(app.CoordStateHistoryAx, [min(double(stateNumArray)) - 0.1, max(double(stateNumArray)) + 0.1]);
            if isempty(rowIdx)
                rowIdx = true(numel(app.BackEnd.CoordAttributeTable.ID), 1);
            end
            legendArray = string(app.BackEnd.CoordAttributeTable.ID(rowIdx));
            legend(app.CoordStateHistoryAx, legendArray(~removeCoord));
            title(app.CoordStateHistoryAx, "Coordinator State History");

            %DISTRIBUTION PLOT
            X = categorical(y);
            X = reordercats(X, y);
            Y = zeros(numel(coordStateCellArray), numel(X));

            for iCoord = 1:numel(coordStateCellArray)

                %Get data
                coordTab = coordStateCellArray{iCoord};
                coordTab(isnan(coordTab.coordState) | isnat(coordTab.Time), :) = [];
                if ~isempty(coordTab) %Must both be non empty
                    state = coordStateCellArray{iCoord}.coordState;
                    durationArray = [hours(diff(coordStateCellArray{iCoord}.Time)); 0];
                    totTime = sum(durationArray);
                    for iState = 1:numel(X)
                        % stateDurationArray(iState) =
                        Y(iCoord, iState) = sum(durationArray(state == iState - 1))/totTime;
                    end

                end
            end

            bar(app.CoordStateDistAx, X, Y)

            legend(app.CoordStateDistAx, legendArray(~removeCoord));
            title(app.CoordStateHistoryAx, "Coordinator State Distribution");
            app.CoordPlotLabel.Text = "Plotted.";
            app.MainFig.Pointer = 'arrow';
            drawnow;
        end

        % Button pushed function: RefreshCoordinatorBox
        function refreshCoordinatorBoxPushed(app, event)
            %Get available calcs based on element, template and current
            %calc state filtering
            app.MainFig.Pointer = 'watch';
            drawnow;

            %First level filtering
            app.collectCalcsOfRootAndTemplate;

            %Second level filtering
            statesList = app.CurrentCalculationStateListBox.Value;
            app.BackEnd.filterByCalcState(statesList);

            %Get used coords
            uniqueCoordIDs = app.BackEnd.getUsedCoordinators;
            app.CalculationCoordinatorListBox.Items = string(uniqueCoordIDs);

            app.RefreshCoordinatorBox.BackgroundColor = [0.96 0.96, 0.96];

            app.MainFig.Pointer = 'arrow';
            drawnow;
        end

        % Image clicked function: HideFilterPanelButton
        function hideFilterPanelButtonClicked(app, event)
            %Hide filter panel
            app.FilterPanel.Visible = 'off';

            %Move calculation table
            app.ShowFilterPanelButton.Visible = 'on';
            app.CalcListPanel.Position(1) = 0;

            %Show calc plot panel
            app.CalcPlotPanel.Visible = 'on';

        end

        % Image clicked function: ShowFilterPanelButton
        function showFilterPanelButtonClicked(app, event)
            %Hide filter panel
            app.FilterPanel.Visible = 'on';

            %Move calculation table
            app.ShowFilterPanelButton.Visible = 'off';
            app.CalcListPanel.Position(1) = 721;

            %Hide calc plot panel
            app.CalcPlotPanel.Visible = 'off';

        end

        % Selection changed function: ElementFilteringTree
        function elementFilteringTreeSelectionChanged(app, event)
            % app.CalculationCoordinatorListBox.Items = {};
            % app.CalculationCoordinatorListBox.Value = {};
            app.showNeedCalcRefresh(true);
        end

        % Value changed function: CurrentCalculationStateListBox
        function currentCalculationStateListBoxValueChanged(app, event)
            app.CalculationCoordinatorListBox.Items = {};
            app.CalculationCoordinatorListBox.Value = {};
            app.showNeedCalcRefresh(true);
        end

        % Button pushed function: PlotselectedButton
        function plotCalcsPushed(app, event)
            %Determine selection
            calcSelection = app.FilteredCalcTab.Selection;
            numVars = numel(app.FilteredCalcTab.Data(:, 1));

            %Get only use selected calcs
            app.BackEnd.getSelectedCalcs(calcSelection, numVars);

            app.createCalcPlots;
        end

        % Value changed function: CalculationCoordinatorListBox
        function calculationCoordinatorListBoxValueChanged(app, event)
            app.RefreshButton.BackgroundColor = [1, 0.8, 0.8];
        end

        % Button pushed function: ReenableselectedButton
        function reenableselectedButtonPushed(app, event)
            %Collect which calcs to reenable
            calcSelection = app.FilteredCalcTab.Selection;
            numVars = numel(app.FilteredCalcTab.Data(:, 1));

            %Get only use selected calcs
            app.BackEnd.getSelectedCalcs(calcSelection, numVars);

            if isempty(calcSelection)
                allCalcsText = " (all calcs in table)";
            else
                allCalcsText = "";
            end

            %Make an are you sure dialogue
            msg = "This will set the selected calculation states to 'Idle/NotAssigned', " + ...
                "if they are in Disabled or SystemDisabled states. " + ...
               string(numel(app.BackEnd.SelectedCalcs)) + allCalcsText + ...
            " calcs selected." +...
            "Are you sure?";

            answer = questdlg(msg,...
                'Calculation Reenable Confirmation',...
            	'Set to Idle', ...
                'Set to NotAssigned',...
            	'Cancel',...
                'Cancel');

            % Handle response
            switch answer
                case 'Set to Idle'
                    app.CalcPlotStatus.Text = "Setting calcs to Idle....";
                    app.MainFig.Pointer = "watch";
                    drawnow;

                    app.BackEnd.reenableDisabledCalcs("Idle");

                    app.MainFig.Pointer = "arrow";
                    app.CalcPlotStatus.Text = "Done.";
                    drawnow;
                case 'Set to NotAssigned'
                    app.CalcPlotStatus.Text = "Setting calcs to NotAssigned....";
                    app.MainFig.Pointer = "watch";
                    drawnow;

                    app.BackEnd.reenableDisabledCalcs("NotAssigned");

                    app.MainFig.Pointer = "arrow";
                    app.CalcPlotStatus.Text = "Done.";
                    drawnow;
                case 'Cancel'
                    app.CalcPlotStatus.Text = "Cancelled reenable.";
                    drawnow;
            end


        end

        % Button pushed function: RequestdisableonselectedButton
        function requestdisableonselectedButtonPushed(app, event)
            %Collect which calcs to reenable
            calcSelection = app.FilteredCalcTab.Selection;
            numVars = numel(app.FilteredCalcTab.Data(:, 1));

            %Get only use selected calcs
            app.BackEnd.getSelectedCalcs(calcSelection, numVars);

            if isempty(calcSelection)
                allCalcsText = " (all calcs in table)";
            else
                allCalcsText = "";
            end

            %Make an are you sure dialogue
            msg = "This will request disable if they are in" +...
                "Idle, FetchingData, Queued, Running or WritingOutputs states. " + ...
                string(numel(app.BackEnd.SelectedCalcs)) + allCalcsText + ...
                " calcs selected." +...
                "Are you sure?";

            answer = questdlg(msg,...
                'Calculation Reenable Confirmation',...
                'Proceed', ...
                'Cancel',...
                'Cancel');

            % Handle response
            switch answer
                case 'Proceed'
                    app.CalcPlotStatus.Text = "Requesting disable on calcs....";
                    app.MainFig.Pointer = "watch";
                    drawnow;

                    app.BackEnd.requestDisableOnCalcs;

                    app.MainFig.Pointer = "arrow";
                    app.CalcPlotStatus.Text = "Done.";
                    drawnow;
                case 'Cancel'
                    app.CalcPlotStatus.Text = "Cancelled disabling.";
                    drawnow;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MainFig = uifigure('Visible', 'off');
            app.MainFig.Name = 'CCE Monitoring Dashboard';
            app.TabGroup = uitabgroup(app.MainFig);
            app.CoordinatorsTab = uitab(app.TabGroup);
            app.CoordinatorsTab.Title = 'Coordinators';
            app.CoordTable = uitable(app.CoordinatorsTab);
            app.CoordTable.ColumnName = {'ID'; 'Load'; 'Exe mode'; 'Exe Freq'; 'Current State'};
            app.CoordTable.RowName = {};
            app.CoordTable.SelectionType = 'row';
            app.CoordTabGroup = uitabgroup(app.CoordinatorsTab);
            app.CoordinatorStateHistoryTab = uitab(app.CoordTabGroup);
            app.CoordinatorStateHistoryTab.Title = 'Coordinator State History';
            app.CoordStateHistoryAx = uiaxes(app.CoordinatorStateHistoryTab);
            title(app.CoordStateHistoryAx, 'Coordinator State History')
            xlabel(app.CoordStateHistoryAx, 'Time')
            ylabel(app.CoordStateHistoryAx, 'State')
            zlabel(app.CoordStateHistoryAx, 'Z')
            app.CoordinatorStateDistributionTab = uitab(app.CoordTabGroup);
            app.CoordinatorStateDistributionTab.Title = 'Coordinator State Distribution';
            app.CoordStateDistAx = uiaxes(app.CoordinatorStateDistributionTab);
            title(app.CoordStateDistAx, 'Coordinator State Distribution')
            xlabel(app.CoordStateDistAx, 'X')
            ylabel(app.CoordStateDistAx, 'Relative time')
            zlabel(app.CoordStateDistAx, 'Z')
            app.PlotSelectedCoordsButton = uibutton(app.CoordinatorsTab, 'push');
            app.PlotSelectedCoordsButton.ButtonPushedFcn = createCallbackFcn(app, @plotSelectedCoordsButtonPushed, true);
            app.PlotSelectedCoordsButton.Text = 'Pull data and plot selected';
            app.CoordPlotLabel = uilabel(app.CoordinatorsTab);
            app.CoordPlotLabel.Text = '';
            app.CalculationsTab = uitab(app.TabGroup);
            app.CalculationsTab.Title = 'Calculations';
            app.FilterPanel = uipanel(app.CalculationsTab);
            app.ElementFilteringTree = uitree(app.FilterPanel);
            app.ElementFilteringTree.Multiselect = 'on';
            app.ElementFilteringTree.SelectionChangedFcn = createCallbackFcn(app, @elementFilteringTreeSelectionChanged, true);
            app.ElementFilteringTree.NodeExpandedFcn = createCallbackFcn(app, @elementFilteringTreeNodeExpanded, true);
            app.ElementFilteringLabel = uilabel(app.FilterPanel);
            app.ElementFilteringLabel.Text = 'Element Hierarchy Filtering ';
            app.SelectAllButtonElement = uibutton(app.FilterPanel, 'push');
            app.SelectAllButtonElement.ButtonPushedFcn = createCallbackFcn(app, @selectAllButtonElementPushed, true);
            app.SelectAllButtonElement.Text = 'All';
            app.TemplateFilteringTree = uitree(app.FilterPanel);
            app.TemplateFilteringTree.Multiselect = 'on';
            app.TemplateFilteringTree.SelectionChangedFcn = createCallbackFcn(app, @templateFilteringTreeSelectionChanged, true);
            app.TemplateFilteringLabel = uilabel(app.FilterPanel);
            app.TemplateFilteringLabel.Text = 'Template/Calculation Type Filtering';
            app.FilterEditFieldLabel = uilabel(app.FilterPanel);
            app.FilterEditFieldLabel.Text = 'Filter';
            app.TemplateFilterEditField = uieditfield(app.FilterPanel, 'text');
            app.TemplateFilterEditField.ValueChangedFcn = createCallbackFcn(app, @templateFilterEditFieldValueChanged, true);
            app.SelectAllButtonTemplate = uibutton(app.FilterPanel, 'push');
            app.SelectAllButtonTemplate.ButtonPushedFcn = createCallbackFcn(app, @selectAllButtonTemplatePushed, true);
            app.SelectAllButtonTemplate.Text = 'All';
            app.CalculationStateCoordinatorFilteringLabel = uilabel(app.FilterPanel);
            app.CalculationStateCoordinatorFilteringLabel.Text = {'Calculation State, Coordinator Filtering'; ''};
            app.CurrentCalculationStateListBoxLabel = uilabel(app.FilterPanel);
            app.CurrentCalculationStateListBoxLabel.Text = 'Current Calculation State';
            app.CurrentCalculationStateListBox = uilistbox(app.FilterPanel);
            app.CurrentCalculationStateListBox.Items = {};
            app.CurrentCalculationStateListBox.Multiselect = 'on';
            app.CurrentCalculationStateListBox.ValueChangedFcn = createCallbackFcn(app, @currentCalculationStateListBoxValueChanged, true);
            app.CurrentCalculationStateListBox.Value = {};
            app.RefreshCoordinatorBox = uibutton(app.FilterPanel, 'push');
            app.RefreshCoordinatorBox.ButtonPushedFcn = createCallbackFcn(app, @refreshCoordinatorBoxPushed, true);
            app.RefreshCoordinatorBox.Text = 'Refresh';
            app.CalculationCoordinatorListBoxLabel = uilabel(app.FilterPanel);
            app.CalculationCoordinatorListBoxLabel.Text = 'Calculation Coordinator';
            app.CalculationCoordinatorListBox = uilistbox(app.FilterPanel);
            app.CalculationCoordinatorListBox.Items = {};
            app.CalculationCoordinatorListBox.Multiselect = 'on';
            app.CalculationCoordinatorListBox.ValueChangedFcn = createCallbackFcn(app, @calculationCoordinatorListBoxValueChanged, true);
            app.CalculationCoordinatorListBox.Value = {};
            app.HideFilterPanelButton = uiimage(app.FilterPanel);
            app.HideFilterPanelButton.ImageClickedFcn = createCallbackFcn(app, @hideFilterPanelButtonClicked, true);
            app.HideFilterPanelButton.ImageSource = fullfile(pathToMLAPP, 'icons', 'skipLeft.png');
            app.CalcListPanel = uipanel(app.CalculationsTab);
            app.FilteredCalculationsLabel = uilabel(app.CalcListPanel);
            app.FilteredCalculationsLabel.Text = 'Filtered Calculations';
            app.RefreshButton = uibutton(app.CalcListPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @refreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.FilteredCalcTab = uitable(app.CalcListPanel);
            app.FilteredCalcTab.ColumnName = {'Name'; 'Template'; 'Coord #'; 'Current State'; 'Backfilling'; 'Exe mode'; 'Last Calc Time'; 'Last Error'; 'Path'};
            app.FilteredCalcTab.RowName = {};
            app.FilteredCalcTab.SelectionType = 'row';
            app.ReenableselectedButton = uibutton(app.CalcListPanel, 'push');
            app.ReenableselectedButton.ButtonPushedFcn = createCallbackFcn(app, @reenableselectedButtonPushed, true);
            app.ReenableselectedButton.Text = 'Re-enable selected';
            app.RequestdisableonselectedButton = uibutton(app.CalcListPanel, 'push');
            app.RequestdisableonselectedButton.ButtonPushedFcn = createCallbackFcn(app, @requestdisableonselectedButtonPushed, true);
            app.RequestdisableonselectedButton.Text = 'Request disable on selected';
            app.ShowFilterPanelButton = uiimage(app.CalcListPanel);
            app.ShowFilterPanelButton.ImageClickedFcn = createCallbackFcn(app, @showFilterPanelButtonClicked, true);
            app.ShowFilterPanelButton.Visible = 'off';
            app.ShowFilterPanelButton.ImageSource = fullfile(pathToMLAPP, 'icons', 'skipRight.png');
            app.PlotselectedButton = uibutton(app.CalcListPanel, 'push');
            app.PlotselectedButton.ButtonPushedFcn = createCallbackFcn(app, @plotCalcsPushed, true);
            app.PlotselectedButton.Text = 'Plot selected';
            app.CalcPlotStatus = uilabel(app.CalcListPanel);
            app.CalcPlotStatus.Text = '';
            app.CalcPlotPanel = uipanel(app.CalculationsTab);
            app.CalcPlotPanel.Visible = 'off';
            app.CalcPlotTabs = uitabgroup(app.CalcPlotPanel);
            app.StateDistributionTab = uitab(app.CalcPlotTabs);
            app.StateDistributionTab.Title = 'State Distribution';
            app.CalcStateDistAxes = uiaxes(app.StateDistributionTab);
            title(app.CalcStateDistAxes, 'Calculation State Distribution')
            xlabel(app.CalcStateDistAxes, 'X')
            ylabel(app.CalcStateDistAxes, 'Y')
            zlabel(app.CalcStateDistAxes, 'Z')
            app.StateHistoryTab = uitab(app.CalcPlotTabs);
            app.StateHistoryTab.Title = 'State History';
            app.CalcStateHistAxes = uiaxes(app.StateHistoryTab);
            title(app.CalcStateHistAxes, 'Calculation State History')
            xlabel(app.CalcStateHistAxes, 'X')
            ylabel(app.CalcStateHistAxes, 'Y')
            zlabel(app.CalcStateHistAxes, 'Z')
            app.OutputvsExecutionTimeTab = uitab(app.CalcPlotTabs);
            app.OutputvsExecutionTimeTab.Title = 'Output vs Execution Time';
            app.OutputvsExecAxes = uiaxes(app.OutputvsExecutionTimeTab);
            title(app.OutputvsExecAxes, 'Ouput time vs Execution Time')
            xlabel(app.OutputvsExecAxes, 'Execution Time')
            ylabel(app.OutputvsExecAxes, 'Output Time')
            zlabel(app.OutputvsExecAxes, 'Z')
            app.LastErrorHistoryTab = uitab(app.CalcPlotTabs);
            app.LastErrorHistoryTab.Title = 'Last Error History';
            app.LastErrorHistAxes = uiaxes(app.LastErrorHistoryTab);
            title(app.LastErrorHistAxes, 'Last Error History')
            xlabel(app.LastErrorHistAxes, 'X')
            ylabel(app.LastErrorHistAxes, 'Y')
            zlabel(app.LastErrorHistAxes, 'Z')
            app.CalculationLagTab = uitab(app.CalcPlotTabs);
            app.CalculationLagTab.Title = 'Calculation Lag';
            app.CalcLagAxes = uiaxes(app.CalculationLagTab);
            title(app.CalcLagAxes, 'Calculation Lag Axes')
            xlabel(app.CalcLagAxes, 'X')
            ylabel(app.CalcLagAxes, 'Y')
            zlabel(app.CalcLagAxes, 'Z')
            app.ConnectRefreshButton = uibutton(app.MainFig, 'push');
            app.ConnectRefreshButton.ButtonPushedFcn = createCallbackFcn(app, @connectButtonPushed, true);
            app.ConnectRefreshButton.Text = 'Connect/Refresh';
            app.AFServerEditFieldLabel = uilabel(app.MainFig);
            app.AFServerEditFieldLabel.Text = 'AF Server';
            app.AFServerEditField = uieditfield(app.MainFig, 'text');
            app.AFServerEditField.Value = 'ons-opcdev.optinum.local';
            app.AFDatabaseEditFieldLabel = uilabel(app.MainFig);
            app.AFDatabaseEditFieldLabel.Text = 'AF Database';
            app.AFDatabaseEditField = uieditfield(app.MainFig, 'text');
            app.AFDatabaseEditField.Value = 'CCEProd';
            app.ConnectionMessage = uilabel(app.MainFig);
            app.ConnectionMessage.Text = '';
            app.StarttimeLabel = uilabel(app.MainFig);
            app.StarttimeLabel.Text = 'Start time:';
            app.StarttimeEditField = uieditfield(app.MainFig, 'text');
            app.StarttimeEditField.Value = '*-10m';
            app.EndTimeLabel = uilabel(app.MainFig);
            app.EndTimeLabel.Text = 'End Time:';
            app.EndTimeEditField = uieditfield(app.MainFig, 'text');
            app.EndTimeEditField.Value = '*';
            app.VersionLabel = uilabel(app.MainFig);
            app.VersionLabel.Text = 'Version';
            % Show the figure after all components are created
            app.MainFig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CCEDashboardFrontEnd

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFig)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFig)
        end
    end
end